classdef matrix_5_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        SliderLabel  matlab.ui.control.Label
        Slider       matlab.ui.control.Slider
        PlayButton   matlab.ui.control.StateButton
        UIAxes       matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            app.PlayButton.Text = "stop";  
            x = [zeros(1,100); (0.1:0.1:10); ones(1,100)]; 
            y = x;

            for i= 0 : 0.02*pi : 5*pi
                power = app.Slider.Value;
                E = [1 exp(-1/5*i)*sin(i) 0; 0 -1 0; 0 0 1;];  
                Ecp = E;
                
                for m = 1:1:100
                    l = power * m/100;
                    Ecp(1,2) = sin(l*E(1,2));
                    Ecp(2,2) = -cos(l*E(1,2));
                    y(:,m) = Ecp * x(:,m);
                end
                
                clf;
                
                app.UIAxes.XLim = ([-5 5]);
                app.UIAxes.YLim = ([-10 0]);

                plot(app.UIAxes,y(1,:),y(2,:));
                app.UIAxes.XGrid = 'on';    
                app.UIAxes.YGrid = 'on';
                
                pause(0.00001);
                if app.PlayButton.Value == 1
                    break;
                end
             end
             app.PlayButton.Text = "play";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.Value = 0.5;
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = {'Play'; ''};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Paper Move')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matrix_5_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end